classdef Convergence_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RealiseY                     matlab.ui.control.UIAxes
        ConvgPlot                    matlab.ui.control.UIAxes
        distribution                 matlab.ui.control.UIAxes
        DistributionTypeButtonGroup  matlab.ui.container.ButtonGroup
        UniformButton                matlab.ui.control.RadioButton
        NormalButton                 matlab.ui.control.RadioButton
        ExponentialButton            matlab.ui.control.RadioButton
        FromnSamplesLabel            matlab.ui.control.Label
        ZoomFrom                     matlab.ui.control.NumericEditField
        TonSamplesLabel              matlab.ui.control.Label
        ZoomTo                       matlab.ui.control.NumericEditField
        TextAreaLabel                matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: DistributionTypeButtonGroup
        function DistributionTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DistributionTypeButtonGroup.SelectedObject;
            if app.UniformButton.Value
                a = app.ZoomFrom.Value;
                b = app.ZoomTo.Value;
                [T, convProb, ASconvergence, error, ConvDist] = UniformConv();
                plot(app.ConvgPlot,a:1:b,convProb(a:1:b),'b',a:1:2000,ASconvergence(a:1:2000),'g',a:1:b,error(a:1:b),'r')
                plot(app.distribution,-2:0.00001:4,ConvDist,'b','LineWidth',2);
                plot(app.RealiseY,1:1:2000,T(1:500,1:1:2000))
                xlim(app.distribution,[0 4]) ;
                ylim(app.ConvgPlot,[0 1.2]) ;
                ylim(app.distribution,[0 1.2]) ;
                legend(app.ConvgPlot,'P','AS','MS')
            elseif app.NormalButton.Value
                a = app.ZoomFrom.Value;
                b = app.ZoomTo.Value;
                [T, convProb, ASconvergence, error, ConvDist] = normalConv();
                plot(app.ConvgPlot,a:1:b,convProb(a:1:b),'b',a:1:1000,ASconvergence(a:1:1000),'g',a:1:b,error(a:1:b), 'r')
                plot(app.distribution,-2:0.00001:4,ConvDist,'b','LineWidth',2);
                plot(app.RealiseY,1:1:2000,T(1:500,1:1:2000))
                xlim(app.distribution,[-1 4]) ;
                ylim(app.ConvgPlot,[0 1.2]) ;
                ylim(app.distribution,[0 1.2]) ;
                legend(app.ConvgPlot,'P','AS','MS')
            elseif app.ExponentialButton.Value
                a = app.ZoomFrom.Value;
                b = app.ZoomTo.Value;
                [T, convProb, ASconvergence, error, ConvDist] = exponentialConv();
                plot(app.ConvgPlot,a:1:b,convProb(a:1:b),'b',a:1:1000,ASconvergence(a:1:1000),'g',a:1:b,error(a:1:b), 'r')
                plot(app.distribution,-1:0.00001:1,ConvDist,'b','LineWidth',2);
                plot(app.RealiseY,1:1:2000,T(1:500,1:1:2000))
                xlim(app.distribution,[0 1]) ;
                ylim(app.ConvgPlot,[0 1.2]) ;
                ylim(app.distribution,[0 1.2]) ;
                legend(app.ConvgPlot,'P','AS','MS')
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RealiseY = uiaxes(app.UIFigure);
            title(app.RealiseY, 'Realisations of Y')
            xlabel(app.RealiseY, 'No of Samples')
            ylabel(app.RealiseY, '|X(n,w) - X(w)|')
            app.ConvgPlot = uiaxes(app.UIFigure);
            title(app.ConvgPlot, 'Convergence')
            xlabel(app.ConvgPlot, 'No of Samples')
            ylabel(app.ConvgPlot, 'prob   AS   MeanSq')
            app.distribution = uiaxes(app.UIFigure);
            title(app.distribution, 'Convergence in Distribution')
            xlabel(app.distribution, 'No of Samples')
            ylabel(app.distribution, 'Cumulative Distributive Function ')
            app.DistributionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DistributionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DistributionTypeButtonGroupSelectionChanged, true);
            app.DistributionTypeButtonGroup.Title = 'Distribution Type';
            app.UniformButton = uiradiobutton(app.DistributionTypeButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.UniformButton.Value = true;
            app.NormalButton = uiradiobutton(app.DistributionTypeButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.ExponentialButton = uiradiobutton(app.DistributionTypeButtonGroup);
            app.ExponentialButton.Text = 'Exponential';
            app.FromnSamplesLabel = uilabel(app.UIFigure);
            app.FromnSamplesLabel.Text = 'From n Samples';
            app.ZoomFrom = uieditfield(app.UIFigure, 'numeric');
            app.ZoomFrom.Limits = [1 1000];
            app.ZoomFrom.Value = 1;
            app.TonSamplesLabel = uilabel(app.UIFigure);
            app.TonSamplesLabel.Text = 'To n Samples';
            app.ZoomTo = uieditfield(app.UIFigure, 'numeric');
            app.ZoomTo.Limits = [100 2000];
            app.ZoomTo.Value = 2000;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Kaushik Ram Selvaraj'; 'Unity ID: kselvar2'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Convergence_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end